classdef UR3simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InicializarcinematicainversaButton  matlab.ui.control.Button
        UselosslidesLabel_2        matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        QudeseasimularButtonGroup  matlab.ui.container.ButtonGroup
        CinematicainversaButton    matlab.ui.control.RadioButton
        CinematicadirectaButton    matlab.ui.control.RadioButton
        UselosslidesLabel          matlab.ui.control.Label
        Q6Slider                   matlab.ui.control.Slider
        Q6Label                    matlab.ui.control.Label
        Q5Slider                   matlab.ui.control.Slider
        Q5SliderLabel              matlab.ui.control.Label
        Q4Slider                   matlab.ui.control.Slider
        Q4SliderLabel              matlab.ui.control.Label
        Q3Slider                   matlab.ui.control.Slider
        Q3Label                    matlab.ui.control.Label
        Q2Slider                   matlab.ui.control.Slider
        Q2SliderLabel              matlab.ui.control.Label
        Q1Slider                   matlab.ui.control.Slider
        Q1Label                    matlab.ui.control.Label
        InicializarcinematicadirectaButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        R;
        Q1=0;
        Q2=0;
        Q3=0;
        Q4=0;
        Q5=0;
        Q6=0;
        x=0;
        y=0;
        z=0;
        Ri=0;
        P=0;
        Y=0;
    end
    
    methods (Access = private)
        
        
        function R=UR3(a)
            L(1) = Link([0 0.152 0 0],'modified');
            L(2) = Link([0 0.120 0 pi/2 0 pi/2],'modified');
            L(3) = Link([0 0 0.244 0],'modified');
            L(4) = Link([0 -0.010 0.213 0 0 -pi/2],'modified');
            L(5) = Link([0 0.083 0 -pi/2],'modified');
            L(6) = Link([0 0.082 0 pi/2],'modified');
            R = SerialLink(L,'name','Universal Robot UR3');
        end
        
        function Rinv(app)
            Rot = rpy2tr(app.Ri,app.P,app.Y);
            trans = transl(app.x,app.y,app.z);
            T = trans*Rot;
            M = [1 1 1 0 0 0];
            qi = ikine(app.R,T,[0,0,0,0,0,0],M,'pinv');
            figure(1)
            app.R.plot(qi,'workspace',[-0.4 0.4 -0.4 0.4 0 0.8], ...
                'noa','view',[59.733 11.020])
        end
        function UR3plot(app)
            r=0.8;
            figure(1)
            app.R.plot([app.Q1 app.Q2 app.Q3 app.Q4 app.Q5 app.Q6], ...
                'workspace',[-r r -r r -r r], 'noa','view',[59.733 11.020])
            title("UR3")
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup_rvc
            app.R = app.UR3();
        end

        % Button pushed function: InicializarcinematicadirectaButton
        function InicializarcinematicadirectaButtonPushed(app, event)
            app.UR3plot;
            app.Q1Slider.Enable = 'on';
            app.Q2Slider.Enable = 'on';
            app.Q3Slider.Enable = 'on';
            app.Q4Slider.Enable = 'on';
            app.Q5Slider.Enable = 'on';
            app.Q6Slider.Enable = 'on';
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.Q1 = event.Value*pi/180;
            app.UR3plot;                
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.Q2 = event.Value*pi/180;
            app.UR3plot;
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.Q3 = event.Value*pi/180;
            app.UR3plot;            
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.Q4 = event.Value*pi/180;
            app.UR3plot;            
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.Q5 = event.Value*pi/180;
            app.UR3plot; 
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            app.Q6 = event.Value*pi/180;
            app.UR3plot; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app)
            
        end

        % Selection changed function: QudeseasimularButtonGroup
        function QudeseasimularButtonGroupSelectionChanged(app, event)
            selectedButton = app.QudeseasimularButtonGroup.SelectedObject;  
            close all;
            if(selectedButton== app.CinematicadirectaButton)
                app.InicializarcinematicadirectaButton.Enable = 'on';
                app.xEditField.Enable = 'off';
                app.yEditField.Enable = 'off';
                app.zEditField.Enable = 'off';
                app.REditField.Enable = 'off';
                app.PEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
                app.REditField.Value = 0;
                app.PEditField.Value = 0;
                app.YEditField.Value = 0;
                
            else
                app.InicializarcinematicadirectaButton.Enable = 'off';
                app.Q1Slider.Enable = 'off';
                app.Q2Slider.Enable = 'off';
                app.Q3Slider.Enable = 'off';
                app.Q4Slider.Enable = 'off';
                app.Q5Slider.Enable = 'off';
                app.Q6Slider.Enable = 'off';
                app.Q1Slider.Value = 0;
                app.Q2Slider.Value = 0;
                app.Q3Slider.Value = 0;
                app.Q4Slider.Value = 0;
                app.Q5Slider.Value = 0;
                app.Q6Slider.Value = 0;
                app.Q1 = 0;
                app.Q2 = 0;
                app.Q3 = 0;
                app.Q4 = 0;
                app.Q5 = 0;
                app.Q6 = 0;
                app.xEditField.Enable = 'on';
                app.yEditField.Enable = 'on';
                app.zEditField.Enable = 'on';
                app.REditField.Enable = 'on';
                app.PEditField.Enable = 'on';      
                app.YEditField.Enable = 'on';        
                app.InicializarcinematicainversaButton.Enable='on';
            end
        end

        % Button pushed function: InicializarcinematicainversaButton
        function InicializarcinematicainversaButtonPushed(app, event)
            app.x = app.xEditField.Value;
            app.y = app.yEditField.Value;
            app.z = app.zEditField.Value;
            app.Ri = app.REditField.Value;
            app.P = app.PEditField.Value;
            app.Y = app.YEditField.Value;
            app.Rinv();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InicializarcinematicadirectaButton = uibutton(app.UIFigure, 'push');
            app.InicializarcinematicadirectaButton.ButtonPushedFcn = createCallbackFcn(app, @InicializarcinematicadirectaButtonPushed, true);
            app.InicializarcinematicadirectaButton.Text = 'Inicializar cinematica directa';
            app.Q1Label = uilabel(app.UIFigure);
            app.Q1Label.Text = {'Q1'; ''};
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [0 360];
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.Enable = 'off';
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [0 360];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2Slider.Enable = 'off';
            app.Q3Label = uilabel(app.UIFigure);
            app.Q3Label.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3Slider.Enable = 'off';
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [0 360];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4Slider.Enable = 'off';
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [0 360];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5Slider.Enable = 'off';
            app.Q6Label = uilabel(app.UIFigure);
            app.Q6Label.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [0 360];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6Slider.Enable = 'off';
            app.UselosslidesLabel = uilabel(app.UIFigure);
            app.UselosslidesLabel.Text = 'Use los slides para indicar la orientación de cada articulación en grados';
            app.QudeseasimularButtonGroup = uibuttongroup(app.UIFigure);
            app.QudeseasimularButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QudeseasimularButtonGroupSelectionChanged, true);
            app.QudeseasimularButtonGroup.Title = '¿Qué desea simular?';
            app.CinematicadirectaButton = uiradiobutton(app.QudeseasimularButtonGroup);
            app.CinematicadirectaButton.Text = 'Cinematica directa';
            app.CinematicadirectaButton.Value = true;
            app.CinematicainversaButton = uiradiobutton(app.QudeseasimularButtonGroup);
            app.CinematicainversaButton.Text = 'Cinematica inversa';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Enable = 'off';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Enable = 'off';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Enable = 'off';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Enable = 'off';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.Enable = 'off';
            app.UselosslidesLabel_2 = uilabel(app.UIFigure);
            app.UselosslidesLabel_2.Text = 'Use los slides para indicar la orientación de cada articulación en grados';
            app.InicializarcinematicainversaButton = uibutton(app.UIFigure, 'push');
            app.InicializarcinematicainversaButton.ButtonPushedFcn = createCallbackFcn(app, @InicializarcinematicainversaButtonPushed, true);
            app.InicializarcinematicainversaButton.Enable = 'off';
            app.InicializarcinematicainversaButton.Text = 'Inicializar cinematica inversa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UR3simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end